classdef ActorRecognizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SetNetworkDefaultButton      matlab.ui.control.Button
        UploadDatasetLabel           matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        EnterFileNameEditField       matlab.ui.control.EditField
        EnterFileNameEditFieldLabel  matlab.ui.control.Label
        ShowArchitectureButton       matlab.ui.control.Button
        LoadNetworkButton            matlab.ui.control.Button
        SaveNetworkButton            matlab.ui.control.Button
        TestVideoButton              matlab.ui.control.Button
        TestImageButton              matlab.ui.control.Button
        TrainNetworkButton           matlab.ui.control.Button
        InitializeCNNButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        actorNet % variable for storing CNN
        datasetFolderLoc % path of dataset folder
        savedFileName % name of file to save network as
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowArchitectureButton
        function ShowArchitectureButtonPushed(app, event)
            import ARclasses.*;
            
            showArchitecture(app.actorNet);
        end

        % Button pushed function: InitializeCNNButton
        function InitializeCNNButtonPushed(app, event)
            import ARclasses.*;
            
            app.actorNet = CNNnetwork(app.datasetFolderLoc);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % upload dataset folder
            app.datasetFolderLoc = uigetdir;
        end

        % Button pushed function: TestImageButton
        function TestImageButtonPushed(app, event)
            import ARclasses.*;
            
            [file,path] = uigetfile();            
            testImage(app.actorNet,file);
        end

        % Button pushed function: TestVideoButton
        function TestVideoButtonPushed(app, event)
            import ARclasses.*;
            
            [file,path] = uigetfile();
            videoClip = Video(file);
            testVideo(app.actorNet,videoClip);
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            % load network function
            import ARclasses.*;
            
            networkFile = uigetfile();
            app.actorNet = loadNetwork(app.actorNet,networkFile);
        end

        % Button pushed function: SaveNetworkButton
        function SaveNetworkButtonPushed(app, event)
            % save network to file function
            import ARclasses.*;
            
            saveNetwork(app.actorNet,app.savedFileName);
        end

        % Value changed function: EnterFileNameEditField
        function EnterFileNameEditFieldValueChanged(app, event)
            % retrieve and save user input as file name for saved file
            app.savedFileName = app.EnterFileNameEditField.Value;
            
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
            % function for training the CNN
            % could be used to train more actors, or add more data, etc
            import ARclasses.*;
            
            trainCNN(app.actorNet);     
        end

        % Button pushed function: SetNetworkDefaultButton
        function SetNetworkDefaultButtonPushed(app, event)
            import ARclasses.*;
            
            app.actorNet = loadNetwork(app.actorNet,'test.mat');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializeCNNButton = uibutton(app.UIFigure, 'push');
            app.InitializeCNNButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeCNNButtonPushed, true);
            app.InitializeCNNButton.Text = 'Initialize CNN';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.TestImageButton = uibutton(app.UIFigure, 'push');
            app.TestImageButton.ButtonPushedFcn = createCallbackFcn(app, @TestImageButtonPushed, true);
            app.TestImageButton.Text = 'Test Image';
            app.TestVideoButton = uibutton(app.UIFigure, 'push');
            app.TestVideoButton.ButtonPushedFcn = createCallbackFcn(app, @TestVideoButtonPushed, true);
            app.TestVideoButton.Text = {'Test Video'; ''};
            app.SaveNetworkButton = uibutton(app.UIFigure, 'push');
            app.SaveNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetworkButtonPushed, true);
            app.SaveNetworkButton.Text = 'Save Network';
            app.LoadNetworkButton = uibutton(app.UIFigure, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.ShowArchitectureButton = uibutton(app.UIFigure, 'push');
            app.ShowArchitectureButton.ButtonPushedFcn = createCallbackFcn(app, @ShowArchitectureButtonPushed, true);
            app.ShowArchitectureButton.Text = 'Show Architecture';
            app.EnterFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterFileNameEditFieldLabel.Text = 'Enter File Name';
            app.EnterFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterFileNameEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'upload.jpg';
            app.Button.Text = '';
            app.UploadDatasetLabel = uilabel(app.UIFigure);
            app.UploadDatasetLabel.Text = 'Upload Dataset';
            app.SetNetworkDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetNetworkDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetNetworkDefaultButtonPushed, true);
            app.SetNetworkDefaultButton.Text = 'Set Network Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActorRecognizer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end